classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ACGroupProjectSSBSCUIFigure    matlab.ui.Figure
        SSBSCGenerationDetectionLabel  matlab.ui.control.Label
        ModulatingFrequencyHzLabel     matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        CarrierFrequencyHzLabel        matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        GOButton                       matlab.ui.control.Button
        SignalSelectorDropDownLabel    matlab.ui.control.Label
        SignalSelectorDropDown         matlab.ui.control.DropDown
        SidebandSelectorLabel          matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        OutputLabel                    matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        CarrierFrequencyHzLabel_2      matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        AnalogCommunicationLabel       matlab.ui.control.Label
        Trimester6Label                matlab.ui.control.Label
        GroupProjectGUIUsingAppDesignerLabel  matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        ModulatedSignalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        ModulatedSignalFrequencyHzEditField  matlab.ui.control.NumericEditField
        RESETButton                    matlab.ui.control.Button
        TimeDomainWaveformLabel        matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)


            v=app.SignalSelectorDropDown.Value;
            
            if(app.DropDown.Value==1)
                assignin('base','mssg',app.EditField.Value);
            assignin('base','carr',app.EditField_2.Value);
            simout = sim('GUI_SSB_F'); 
            
            mssg = app.EditField.Value;
            carr = app.EditField_2.Value;
            app.ModulatedSignalFrequencyHzEditField.Value = mssg+carr;
            
            switch v
                case '1'
                    plot(app.UIAxes_2,simout.Mess_sgnl.time,simout.Mess_sgnl.Data);
                    t=title(app.UIAxes_2,'\color{white}Message Signal');
                    t.FontWeight='bold';
                case '2'
                    plot(app.UIAxes_2,simout.Mess_sgnl_90.time,simout.Mess_sgnl_90.Data);
                    t=title(app.UIAxes_2,'\color{white}90 degree Phase Shifted Message Signal');
                    t.FontWeight='bold';
                case '3'
                      plot(app.UIAxes_2,simout.carr_sgnl.time,simout.carr_sgnl.Data);
                      t=title(app.UIAxes_2,'\color{white}Carrier Signal');
                      t.FontWeight='bold';
                case '4'
                      plot(app.UIAxes_2,simout.carr_sgnl_90.time,simout.carr_sgnl_90.Data);
                      t=title(app.UIAxes_2,'\color{white}90 degree Phase Shifted Carrier Signal');
                      t.FontWeight='bold';
                case '5'
                      plot(app.UIAxes_2,simout.Modulated.time,simout.Modulated.Data);
                      t=title(app.UIAxes_2,'\color{white}Modulated Signal (SSB-SC)');
                      t.FontWeight='bold';
                 case '6'
                      plot(app.UIAxes_2,simout.Demodulated.time,simout.Demodulated.Data);
                      t=title(app.UIAxes_2,'\color{white}Demodulated Signal');
                      t.FontWeight='bold';
            end
            else
                assignin('base','mssg',app.EditField.Value);
            assignin('base','carr',app.EditField_2.Value);
            simout = sim('LSB_Spectrum.slx'); 
            
            mssg = app.EditField.Value;
            carr = app.EditField_2.Value;
            app.ModulatedSignalFrequencyHzEditField.Value = carr-mssg;
            
            switch v
                case '1'
                    plot(app.UIAxes_2,simout.Mess_sgnl.time,simout.Mess_sgnl.Data);
                    t=title(app.UIAxes_2,'\color{white}Message Signal');
                    t.FontWeight='bold';
                case '2'
                    plot(app.UIAxes_2,simout.Mess_sgnl_90.time,simout.Mess_sgnl_90.Data);
                    t=title(app.UIAxes_2,'\color{white}90 degree Phase Shifted Message Signal');
                    t.FontWeight='bold';
                case '3'
                      plot(app.UIAxes_2,simout.carr_sgnl.time,simout.carr_sgnl.Data);
                      t=title(app.UIAxes_2,'\color{white}Carrier Signal');
                      t.FontWeight='bold';
                case '4'
                      plot(app.UIAxes_2,simout.carr_sgnl_90.time,simout.carr_sgnl_90.Data);
                      t=title(app.UIAxes_2,'\color{white}90 degree Phase Shifted Carrier Signal');
                      t.FontWeight='bold';
                case '5'
                      plot(app.UIAxes_2,simout.Modulated.time,simout.Modulated.Data);
                      t=title(app.UIAxes_2,'\color{white}Modulated Signal (SSB-SC)');
                      t.FontWeight='bold';
                case '6'
                      plot(app.UIAxes_2,simout.Demodulated.time,simout.Demodulated.Data);
                      t=title(app.UIAxes_2,'\color{white}Demodulated Signal');
                      t.FontWeight='bold';
            end
            end


        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            cla(app.UIAxes_2)
            default = 0;
            app.EditField.Value = default;
            app.EditField_2.Value = default;
            app.ModulatedSignalFrequencyHzEditField.Value = default;
            t=title(app.UIAxes_2,'');
            t.FontWeight='bold';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ACGroupProjectSSBSCUIFigure = uifigure('Visible', 'off');
            app.ACGroupProjectSSBSCUIFigure.Name = 'AC Group Project: SSB-SC';
            app.SSBSCGenerationDetectionLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.SSBSCGenerationDetectionLabel.Text = 'SSB-SC Generation & Detection';
            app.ModulatingFrequencyHzLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.ModulatingFrequencyHzLabel.Text = 'Modulating Frequency (Hz)';
            app.EditField = uieditfield(app.ACGroupProjectSSBSCUIFigure, 'numeric');
            app.EditField.Limits = [0 100];
            app.CarrierFrequencyHzLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.CarrierFrequencyHzLabel.Text = 'Carrier Frequency (Hz)';
            app.EditField_2 = uieditfield(app.ACGroupProjectSSBSCUIFigure, 'numeric');
            app.EditField_2.Limits = [0 2000];
            app.GOButton = uibutton(app.ACGroupProjectSSBSCUIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.SignalSelectorDropDownLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.SignalSelectorDropDownLabel.Text = {'Signal Selector'; ''};
            app.SignalSelectorDropDown = uidropdown(app.ACGroupProjectSSBSCUIFigure);
            app.SignalSelectorDropDown.Items = {'Message signal', 'Message signal_90', 'Carrier signal', 'Carrier signal_90', 'Modulated signal', 'Demodulated signal'};
            app.SignalSelectorDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.SignalSelectorDropDown.Value = '5';
            app.SidebandSelectorLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.SidebandSelectorLabel.Text = 'Sideband Selector';
            app.DropDown = uidropdown(app.ACGroupProjectSSBSCUIFigure);
            app.DropDown.Items = {'Upper Sideband (USB)', 'Lower Sideband (LSB)', ''};
            app.DropDown.ItemsData = [1 2];
            app.DropDown.Value = 1;
            app.OutputLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.OutputLabel.Text = 'Output:';
            app.TextArea = uitextarea(app.ACGroupProjectSSBSCUIFigure);
            app.TextArea.Value = {'GUI Designed By:'; '1) Satyam Morankar PC38'; '2) Nishant Jain PC36'};
            app.CarrierFrequencyHzLabel_2 = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.CarrierFrequencyHzLabel_2.Text = 'Carrier Frequency (Hz)';
            app.Image_2 = uiimage(app.ACGroupProjectSSBSCUIFigure);
            app.Image_2.ImageSource = 'GUI_IMG1.jpg';
            app.AnalogCommunicationLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.AnalogCommunicationLabel.Text = 'Analog Communication';
            app.Trimester6Label = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.Trimester6Label.Text = 'Trimester - 6';
            app.GroupProjectGUIUsingAppDesignerLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.GroupProjectGUIUsingAppDesignerLabel.Text = 'Group Project:  GUI Using App Designer';
            app.TextArea_2 = uitextarea(app.ACGroupProjectSSBSCUIFigure);
            app.TextArea_2.Value = {'Project Guide:'; 'Prof. Dr. Sumitra Motade '};
            app.ModulatedSignalFrequencyHzEditFieldLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.ModulatedSignalFrequencyHzEditFieldLabel.Text = 'Modulated Signal Frequency (Hz)';
            app.ModulatedSignalFrequencyHzEditField = uieditfield(app.ACGroupProjectSSBSCUIFigure, 'numeric');
            app.RESETButton = uibutton(app.ACGroupProjectSSBSCUIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.TimeDomainWaveformLabel = uilabel(app.ACGroupProjectSSBSCUIFigure);
            app.TimeDomainWaveformLabel.Text = 'Time Domain Waveform:';
            app.UIAxes_2 = uiaxes(app.ACGroupProjectSSBSCUIFigure);
            xlabel(app.UIAxes_2, 'Time (Sec)')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.ACGroupProjectSSBSCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ACGroupProjectSSBSCUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ACGroupProjectSSBSCUIFigure)
        end
    end
end